classdef DO_AN_PPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        IterLabel                      matlab.ui.control.Label
        solanlapLabel                  matlab.ui.control.Label
        RootLabel                      matlab.ui.control.Label
        ketquanghiemLabel              matlab.ui.control.Label
        ChnphngphpDropDownLabel_2      matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        epsEditField                   matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel    matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        FuncEditField                  matlab.ui.control.EditField
        NhpphngtrnhfxEditFieldLabel    matlab.ui.control.Label
        CalcButton                     matlab.ui.control.Button
        khoangphanlyLabel              matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        noisuy                         matlab.ui.container.Tab
        tim                            matlab.ui.control.Button
        giatri_y                       matlab.ui.control.EditField
        yEditFieldLabel                matlab.ui.control.Label
        giatri_x                       matlab.ui.control.EditField
        xEditFieldLabel                matlab.ui.control.Label
        ketqua_noisuy                  matlab.ui.control.TextArea
        KtqunisuyTextAreaLabel         matlab.ui.control.Label
        ketqua_dathuc                  matlab.ui.control.TextArea
        KtquathcnisuyTextAreaLabel     matlab.ui.control.Label
        giatri_x0                      matlab.ui.control.NumericEditField
        GitrcnnisuyEditFieldLabel      matlab.ui.control.Label
        phuongphap                     matlab.ui.control.DropDown
        ChnPhngphpDropDownLabel        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        KtqudonEditField               matlab.ui.control.EditField
        KtqudonEditFieldLabel          matlab.ui.control.Label
        KtquphngtrnhhiquyEditField     matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        GiiButton                      matlab.ui.control.Button
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        ChnphngphphiquyDropDown        matlab.ui.control.DropDown
        ChnphngphphiquyDropDownLabel   matlab.ui.control.Label
        NhpgitryEditField              matlab.ui.control.EditField
        yEditFieldLabel_3              matlab.ui.control.Label
        NhpgitrxEditField              matlab.ui.control.EditField
        xEditFieldLabel_3              matlab.ui.control.Label
        NhpgitrcndonEditField          matlab.ui.control.EditField
        UIAxes_2                       matlab.ui.control.UIAxes
        tichphan                       matlab.ui.container.Tab
        note                           matlab.ui.control.Label
        nhapham                        matlab.ui.control.EditField
        fLabel                         matlab.ui.control.Label
        kq                             matlab.ui.control.TextArea
        KtquTextAreaLabel              matlab.ui.control.Label
        tim_tp                         matlab.ui.control.Button
        n                              matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        can_b                          matlab.ui.control.EditField
        CnbEditFieldLabel              matlab.ui.control.Label
        can_a                          matlab.ui.control.EditField
        CnaEditFieldLabel              matlab.ui.control.Label
        chon_pp                        matlab.ui.control.DropDown
        ChnphngphpDropDownLabel        matlab.ui.control.Label
        giatri_y_2                     matlab.ui.control.EditField
        yEditFieldLabel_2              matlab.ui.control.Label
        giatri_x_2                     matlab.ui.control.EditField
        xEditFieldLabel_2              matlab.ui.control.Label
        chon_kieu_tinh                 matlab.ui.control.Switch
        ohmTab                         matlab.ui.container.Tab
        PPDropDown                     matlab.ui.control.DropDown
        ChnphngphpohmDropDownLabel     matlab.ui.control.Label
        x0Field                        matlab.ui.control.EditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        SaisoDropDown                  matlab.ui.control.DropDown
        ChnsaisDropDownLabel           matlab.ui.control.Label
        KtquEditField                  matlab.ui.control.EditField
        KtquEditFieldLabel             matlab.ui.control.Label
        hField                         matlab.ui.control.EditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        hamsoField                     matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        yField                         matlab.ui.control.EditField
        NhpgitryEditField_2Label       matlab.ui.control.Label
        xField                         matlab.ui.control.EditField
        NhpgitrxEditField_2Label       matlab.ui.control.Label
        ChndnguvoDropDown              matlab.ui.control.DropDown
        ChndnguvoDropDownLabel         matlab.ui.control.Label
        GiiButton_2                    matlab.ui.control.Button
        GiithiunhmTab                  matlab.ui.container.Tab
        HTMLGioiThieu                  matlab.ui.control.HTML
    end

    
    methods (Access = private)
        
        function I = TichphanHinhThang(app, fx, a, b, N)
            try
                h = (b - a)/N;
                fx = str2func(['@(x)', fx]);
                I = (h/2)*(fx(a) + fx(b) + 2*sum(fx(a+h:h:b-h)));
            catch
                I = 'Nhập sai cú pháp';
            end

        end
        
        function I = Simpson13(app, fx, a, b, N)
            try
                h = (b - a)/N;
                fx = str2func(['@(x)', fx]);
                I = (h/3)*(fx(a) + fx(b) + 4*sum(fx(a+h:2*h:b-h)) + 2*sum(fx(a+2*h:2*h:b-2*h)));
            catch
                I = 'Nhập sai cú pháp';
            end
        end
        
        function I = Simpson38(app, fx, a, b, N)
            try
                h  = (b - a)/N;
                f  = str2func(['@(x) ' fx]);   
                S = f(a) + f(b);
                for k = 1:(N-1)
                    xk = a + k*h;
                    if mod(k,3) == 0
                        S = S + 2*f(xk); 
                    else
                        S = S + 3*f(xk);  
                    end
                end
                I = 3*h/8 * S;
            catch
                I = 'Nhập sai cú pháp';
            end
        end
%===============================================================
        function I = TichphanHinhThangXY(app, x, y, a, b)
            try
                x = x(:);  y = y(:);
                idx = (x >= a) & (x <= b);
                x = x(idx);  y = y(idx);
                if length(x) < 2
                    I = 'Nhập sai cú pháp hoặc x, y, a, b không hợp lệ';
                    return;
                end
                h = diff(x);
                I = sum( h .* (y(1:end-1) + y(2:end))/2 );
            catch
                I = 'Lỗi dữ liệu';
            end
        end
        
        function I = Simpson13XY(app, x, y, a, b)
            try
                x = x(:);  y = y(:);
                idx = (x >= a) & (x <= b);
                x = x(idx);  y = y(idx);
                nn = length(x) - 1;
                if nn < 2 || mod(nn,2) ~= 0
                    I = 'Nhập sai cú pháp hoặc x, y, a, b không hợp lệ';
                    return;
                end
                h = x(2) - x(1);
                I = (h/3) * (y(1) + y(end) ...
                     + 4*sum(y(2:2:end-1)) ...
                     + 2*sum(y(3:2:end-2)));
            catch
                I = 'Nhập sai cú pháp hoặc x, y, a, b không hợp lệ';
            end
        end
        
        function I = Simpson38XY(app, x, y, a, b)
            try
                x = x(:);  y = y(:);
                idx = (x >= a) & (x <= b);
                x = x(idx);  y = y(idx);
                nn = length(x) - 1;
                if nn < 3 || mod(nn,3) ~= 0
                    I = 'Nhập sai cú pháp hoặc x, y, a, b không hợp lệ';
                    return;
                end
                h = x(2) - x(1);
                S = y(1) + y(end);
                for i = 2:nn
                    if mod(i-1,3) == 0
                        S = S + 2*y(i);
                    else
                        S = S + 3*y(i);
                    end
                end
                I = 3*h/8 * S;
            catch
                I = 'Nhập sai cú pháp hoặc x, y, a, b không hợp lệ';
            end
        end


        %===========================================
        function [a1, a0, r2] = HoiQuyTuyenTinh(app,x, y)
             nn = length(x);
             sumx = 0;
             sumy = 0;
             sumxy = 0;
             sumx2 = 0;
             st = 0;
             sr = 0;
             for i = 1:nn
                 sumx = sumx + x(i);
                 sumy = sumy + y(i);
                 sumxy = sumxy + x(i) * y(i);
                 sumx2 = sumx2 + x(i) * x(i);
             end
             xm = sumx / nn;
             ym = sumy / nn;
             a1 = (nn * sumxy - sumx * sumy) / (nn*sumx2 - sumx*sumx);
             a0 = ym - a1 * xm;
             for i = 1: nn
                 st = st + (y(i) - ym)^2;
                 sr = sr + (y(i) - a1 * x(i) - a0)^2;
             end
             r2 = (st - sr) / st;
         end

%================================================================
            function [c,iter] = bisection_local(app,f,a,b,es)
                iter = 0;
                while (b-a)/2 > es && iter < 100
                    c = (a+b)/2;
                    if f(c) == 0, break; end
                    if f(a)*f(c) < 0
                        b = c;
                    else
                        a = c;
                    end
                    iter = iter + 1;
                end
                c = (a+b)/2;
            end
        
            function [root,iter] = fixed_local(app,g,x0,es)
                iter = 0;
                x_old = x0;
                while iter < 100
                    x_new = g(x_old);
                    if abs(x_new - x_old) < es
                        root = x_new;
                        return;
                    end
                    x_old = x_new;
                    iter = iter + 1;
                end
                root = x_new;
            end
        
            function [root,iter] = newton_local(app,f,df,x0,es)
                iter = 0;
                x_old = x0;
                while iter < 100
                    fx = f(x_old);
                    dfx = df(x_old);
                    if dfx == 0
                        error('Đạo hàm bằng 0!');
                    end
                    x_new = x_old - fx/dfx;
                    if abs(x_new - x_old) < es
                        root = x_new;
                        return;
                    end
                    x_old = x_new;
                    iter = iter + 1;
                end
                root = x_new;
            end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.nhapham.Visible = 'off';
            app.fLabel.Visible = 'off';
            app.note.Visible = 'off';
            app.n.Visible = 'off';
            app.NEditFieldLabel.Visible = 'off';


            app.hamsoField.Enable = 'off';
            app.hField.Enable = 'off';

htmlArray = [ 
        "<!DOCTYPE html>"
        "<html>"
        "<head>"
        "   <meta charset='utf-8'>"
        "   <style>"
        "       body { font-family: 'Segoe UI', sans-serif; background-color: #f4f4f4; padding: 20px; display: flex; flex-direction: column; align-items: center; }"
        "       .header { color: #0072BD; font-size: 22px; font-weight: bold; border-bottom: 3px solid #0072BD; margin-bottom: 20px; padding-bottom: 5px; }"
        "       .card { background: white; width: 90%; max-width: 500px; padding: 15px; margin-bottom: 15px; border-radius: 8px; box-shadow: 0 2px 4px rgba(0,0,0,0.1); border-left: 6px solid #D95319; display: flex; justify-content: space-between; align-items: center; }"
        "       .name { font-size: 16px; font-weight: bold; color: #333; }"
        "       .mssv { font-size: 13px; color: #666; font-style: italic; margin-top: 3px; }"
        "       .badge { background-color: #e6f7ff; color: #0072BD; padding: 5px 10px; border-radius: 12px; font-size: 11px; font-weight: bold; border: 1px solid #91d5ff; }"
        "       /* CSS cho phần Link GitHub */"
        "       .URL { width: 100%; text-align: center; font-size: 14px; }"
        "       a { color: #0072BD; text-decoration: none; font-weight: bold; transition: color 0.3s; }"
        "       a:hover { color: #D95319; text-decoration: underline; }"
        "   </style>"
        "</head>"
        "<body>"
        "   <div class='header'>THÀNH VIÊN NHÓM</div>"
        
        "   "
        "   <div class='card'>"
        "       <div>"
        "           <div class='name'>Đặng Quốc Bình</div>"
        "           <div class='mssv'>MSSV: 23207039</div>"
        "       </div>"
        "       <div class='badge'>Hồi quy, Đạo hàm</div>"
        "   </div>"
        
        "   "
        "   <div class='card'>"
        "       <div style='border-left-color: #EDB120;'>" 
        "           <div class='name'>Huỳnh Tấn Đạt</div>"
        "           <div class='mssv'>MSSV: 23207042</div>"
        "       </div>"
        "       <div class='badge'>Nội suy, Tích phân</div>"
        "   </div>"
        
        "   "
        "   <div class='card'>"
        "       <div>"
        "           <div class='name'>Nguyễn Tuấn Kiệt</div>"
        "           <div class='mssv'>MSSV: 23207008</div>"
        "       </div>"
        "       <div class='badge'>Tìm nghiệm, Báo cáo đồ án</div>"
        "   </div>"

        "   "
        "   <div class='card' style='border-left: 6px solid #77AC30;'>"
        "       <div class='URL'>"
        "           Source Code: "
        "           <a href='https://github.com/Nutuki2k5/DoAn_PPT_HCMUS_2025' target='_blank'>"
        "               https://github.com/Nutuki2k5/DoAn_PPT_HCMUS_2025"
        "           </a>"
        "       </div>"
        "   </div>"

        "</body>" 
        "</html>" 
    ];
    app.HTMLGioiThieu.HTMLSource = join(htmlArray, "");
        end

        % Value changed function: giatri_x0
        function giatri_x0ValueChanged(app, event)
            
            
        end

        % Value changing function: ketqua_noisuy
        function ketqua_noisuyValueChanging(app, event)
            app.ketqua_noisuy.Value = event.Value;
  
        end

        % Value changing function: ketqua_dathuc
        function ketqua_dathucValueChanging(app, event)
            app.ketqua_dathuc.Value = event.Value;
       
        end

        % Value changed function: phuongphap
        function phuongphapValueChanged(app, event)

        end

        % Value changing function: giatri_y
        function giatri_yValueChanging(app, event)
            app.giatri_y.Value = event.Value;
      
        end

        % Value changing function: giatri_x
        function giatri_xValueChanging(app, event)
            app.giatri_x.Value = event.Value;
      
        end

        % Button pushed function: tim
        function timButtonPushed(app, event)
            x0 = app.giatri_x0.Value;
            ya = str2num(app.giatri_y.Value);
            xa = str2num(app.giatri_x.Value);


            pp = app.phuongphap.Value;
            switch pp
                case ' '
                        app.ketqua_noisuy.Value = {'Chọn Phương pháp'};
                        app.ketqua_dathuc.Value = {'Chọn Phương pháp'};
                case 'Larange'
                    if isempty(xa) || isempty(ya) || isempty(app.giatri_x0.Value) || length(xa) <= 1 || length(ya) <= 1 || numel(xa)~=numel(ya)
                        app.ketqua_noisuy.Value = {'Nhập thiếu dữ liệu'};
                        app.ketqua_dathuc.Value = {'Nhập thiếu dữ liệu'};
                    elseif size(xa,1) ~= 1 || size(ya,1) ~= 1
                        app.ketqua_noisuy.Value = {'Nhập ma trận 1 hàng'};
                        app.ketqua_dathuc.Value = {'Nhập ma trận 1 hàng'};
                    else
                        syms x;
                        nn = length(xa);
                        result = 0; 
                        for a = 1:nn
                            product = ya(a);
                            for b = 1:nn
                                if a ~= b
                                    product = product * (x - xa(b)) / (xa(a) - xa(b));
                                end
                            end 
                            result = result + product;
                        end
                        result_dt = result;
                        app.ketqua_dathuc.Value = {['y = ' char(expand(result))]};
                        result = double(subs(result, x, x0));
                        app.ketqua_noisuy.Value = {num2str(result)};
                        fh = matlabFunction(result_dt);     
                        xx = linspace(min(xa), max(xa), 200); 
                        yy = fh(xx); 
                        plot(app.UIAxes, xa, ya, 'or', xx, yy, 'b');
                        legend(app.UIAxes, {'Dữ liệu x, y nhập vào','Đa thức nội suy'});
                    end
                case 'Newton'
                    if isempty(xa) || isempty(ya) || isempty(app.giatri_x0.Value) || length(xa) <= 1 || length(ya) <= 1 || numel(xa)~=numel(ya)
                        app.ketqua_noisuy.Value = {'Nhập thiếu dữ liệu'};
                        app.ketqua_dathuc.Value = {'Nhập thiếu dữ liệu'};
                    elseif size(xa,1) ~= 1 || size(ya,1) ~= 1
                        app.ketqua_noisuy.Value = {'Nhập ma trận 1 hàng'};
                        app.ketqua_dathuc.Value = {'Nhập ma trận 1 hàng'};
                    else
                        syms x;
                        n1 = length(xa);
                        da = ya;
                        for a = 1:n1
                            for b = 1:(a-1)
                                da(a) = (da(b) - da(a)) / (xa(b) - xa(a));
                            end
                        end
                        n2 = length(da);
                        result = da(n2);
    
                        for a = (n2-1):-1:1
                            result = result * (x - xa(a)) + da(a);
                        end
                        result_dt = result;
                        app.ketqua_dathuc.Value = {['y = ' char(expand(result))]};
                        result = double(subs(result, x, x0));
                        app.ketqua_noisuy.Value = {num2str(result)};

                        fh = matlabFunction(result_dt);     
                        xx = linspace(min(xa), max(xa), 200); 
                        yy = fh(xx); 
                        plot(app.UIAxes, xa, ya, 'or', xx, yy, 'b');
                        legend(app.UIAxes, {'Dữ liệu x, y nhập vào','Đa thức nội suy'});
                    end
            end
        end

        % Value changed function: giatri_x_2
        function giatri_x_2ValueChanged(app, event)
            
            
        end

        % Value changed function: giatri_y_2
        function giatri_y_2ValueChanged(app, event)
            
            
        end

        % Button pushed function: tim_tp
        function tim_tpPushed(app, event)
            xa_2 = str2num(app.giatri_x_2.Value);
            ya_2 = str2num(app.giatri_y_2.Value);
            cana = str2double(app.can_a.Value);
            canb = str2double(app.can_b.Value);
            giatri_n = app.n.Value;
            f = app.nhapham.Value;
            if isempty(cana) || isempty(canb)
                app.kq.Value = {'Nhập thiếu dữ liệu a, b'};
            else
                if strcmp(app.chon_kieu_tinh.Value, 'Nhập hàm')
                    if isempty(f) || isempty(giatri_n)
                        app.kq.Value = {'Nhập thiếu f hoặc N'};
                    else
                        if strcmp(app.chon_pp.Value, 'Hình thang')
                            I = app.TichphanHinhThang(f, cana, canb, giatri_n);
                            if strcmp(I, 'Nhập sai cú pháp')
                                app.kq.Value = {I};
                            else
                                app.kq.Value = {num2str(I)};
                            end
                        elseif strcmp(app.chon_pp.Value, 'Simpson 1/3')
                            I = app.Simpson13(f, cana, canb, giatri_n);
                            if strcmp(I, 'Nhập sai cú pháp')
                                app.kq.Value = {I};
                            else
                                app.kq.Value = {num2str(I)};
                            end
                        elseif strcmp(app.chon_pp.Value, 'Simpson 3/8')
                            if mod(giatri_n,3) ~= 0
                                app.kq.Value = {'N phải là bội số của 3 cho Simpson 3/8'};
                            
                            else
                                I = app.Simpson38(f, cana, canb, giatri_n);
                                if strcmp(I, 'Nhập sai cú pháp')
                                    app.kq.Value = {I};
                                else
                                    app.kq.Value = {num2str(I)};
                                end
                            end
                        end

                    end


                elseif strcmp(app.chon_kieu_tinh.Value, 'Nhập giá trị')
                        if length(xa_2) <= 1 || length(ya_2) <= 1 || numel(xa_2)~=numel(ya_2)
                            app.kq.Value = {'Nhập thiếu dữ liệu'};
                        elseif size(xa_2,1) ~= 1 || size(ya_2,1) ~= 1
                            app.kq.Value = {'Nhập ma trận 1 hàng'};
                        else
                            if isempty(xa_2) || isempty(ya_2)
                                app.kq.Value = {'Nhập thiếu x hoặc y'};
                            else
                                if strcmp(app.chon_pp.Value, 'Hình thang')
                                    I = app.TichphanHinhThangXY(xa_2, ya_2, cana, canb);
                                    if strcmp(I, 'Nhập sai cú pháp hoặc x, y, a, b không hợp lệ')
                                        app.kq.Value = {I};
                                    else
                                        app.kq.Value = {num2str(I)};
                                    end
                                elseif strcmp(app.chon_pp.Value, 'Simpson 1/3')
                                    I = app.Simpson13XY(xa_2, ya_2, cana, canb);
                                    if strcmp(I, 'Nhập sai cú pháp hoặc x, y, a, b không hợp lệ')
                                        app.kq.Value = {I};
                                    else
                                        app.kq.Value = {num2str(I)};
                                    end
                                elseif strcmp(app.chon_pp.Value, 'Simpson 3/8')
                                    I = app.Simpson38XY(xa_2, ya_2, cana, canb);
                                    if strcmp(I, 'Nhập sai cú pháp hoặc x, y, a, b không hợp lệ')
                                        app.kq.Value = {I};
                                    else
                                        app.kq.Value = {num2str(I)};
                                    end
                                end
                            end
                        end
                end
            end
 
        end

        % Value changed function: chon_pp
        function chon_ppValueChanged(app, event)
            
            
        end

        % Value changed function: can_a
        function can_aValueChanged(app, event)
            
            
        end

        % Value changed function: can_b
        function can_bValueChanged(app, event)
            
            
        end

        % Value changed function: n
        function nValueChanged(app, event)
            
            
        end

        % Value changed function: kq
        function kqValueChanged(app, event)
           
            
        end

        % Value changed function: chon_kieu_tinh
        function chon_kieu_tinhValueChanged(app, event)
            chonkt = app.chon_kieu_tinh.Value;
             switch chonkt
             case 'Nhập giá trị'
                 app.nhapham.Visible = 'off';
                 app.fLabel.Visible = 'off';
                 app.note.Visible = 'off';
                 app.n.Visible = 'off';
                 app.NEditFieldLabel.Visible = 'off';

                 app.giatri_x_2.Visible = 'on';
                 app.giatri_y_2.Visible = 'on';
                 app.xEditFieldLabel_2.Visible = 'on';
                 app.yEditFieldLabel_2.Visible = 'on';
             case 'Nhập hàm'
                 app.giatri_x_2.Visible = 'off';
                 app.giatri_y_2.Visible = 'off';
                 app.xEditFieldLabel_2.Visible = 'off';
                 app.yEditFieldLabel_2.Visible = 'off';

                 app.nhapham.Visible = 'on';
                 app.fLabel.Visible = 'on';
                 app.note.Visible = 'on';
                 app.n.Visible = 'on';
                 app.NEditFieldLabel.Visible = 'on';
            end
        end

        % Value changed function: nhapham
        function nhaphamValueChanged(app, event)
  
        end

        % Button pushed function: GiiButton
        function GiiButtonPushed(app, event)
            x = str2num(app.NhpgitrxEditField.Value);
            y = str2num(app.NhpgitryEditField.Value);
        
            if isempty(x) || isempty(y)
                app.KtquphngtrnhhiquyEditField.Value = "Lỗi: nhập x,y chưa đúng!";
                app.KtqudonEditField.Value = "";
                return;
            end
        
            if length(x) ~= length(y)
                app.KtquphngtrnhhiquyEditField.Value = "Lỗi: x và y phải cùng số phần tử!";
                app.KtqudonEditField.Value = "";
                return;
            end
        
            % Lấy giá trị cần dự đoán
            x_pred = str2double(app.NhpgitrcndonEditField.Value);
        
            if isnan(x_pred)
                app.KtqudonEditField.Value = "Lỗi: giá trị dự đoán không hợp lệ!";
                return;
            end
        
            % Phương pháp hồi quy
            pp = app.ChnphngphphiquyDropDown.Value;
        
            % HỒI QUY TUYẾN TÍNH
           
            if strcmp(pp, "Tuyến tính")
                [a1, a0, r2] = app.HoiQuyTuyenTinh(x,y);
                y1 = a0 +a1.*x;
                y_pred = a1 * x_pred + a0;
                plot(app.UIAxes_2, x, y, 'o', x, y1, 'r', x_pred, y_pred,'*b');
               
                legend(app.UIAxes_2, {'Giá trị đo được','Đường thẳng hồi quy', 'Giá trị dự đoán'});
                app.KtquphngtrnhhiquyEditField.Value = ...
                    sprintf("y = %.4f*x + %.4f và r2 = %f", a1, a0, r2);
        
           
            % HỒI QUY HÀM MŨ
           
            elseif strcmp(pp, "ax^b")
                if any(y <= 0) || any(x <= 0)
                    app.KtquphngtrnhhiquyEditField.Value = "y > 0 , x > 0";
                    return;
                end
                Y = log(y);
                X = log(x);

                [a1, a0, r2] = app.HoiQuyTuyenTinh(X,Y);
                b = a1;
                a = exp(a0);
                y1 = a.*x.^b;

                y_pred = a.*x_pred.^b;

                plot(app.UIAxes_2, x, y, 'o', x, y1, 'r', x_pred, y_pred,'*b');
                legend(app.UIAxes_2, {'Giá trị đo được','Đường thẳng hồi quy', 'Giá trị dự đoán'});

                app.KtquphngtrnhhiquyEditField.Value = ...
                    sprintf("y = %.4f*x ^ %.4f  và r2 = %f", a, b, r2);


            else
                if any(y <= 0) || any(x <= 0)
                    app.KtquphngtrnhhiquyEditField.Value = "y > 0 , x > 0";
                    return;
                end
                Y = log(y);
                [a1, a0, r2] = app.HoiQuyTuyenTinh(x,Y);
                b = a1 / log(exp(1));
                a = exp(a0);
                y1 = a .* exp(b.*x);

                y_pred = a .* exp(b.*x_pred);

                plot(app.UIAxes_2, x, y, 'o', x, y1, 'r', x_pred, y_pred,'*b');
                legend(app.UIAxes_2, {'Giá trị đo được','Đường thẳng hồi quy', 'Giá trị dự đoán'});

                app.KtquphngtrnhhiquyEditField.Value = ...
                    sprintf("y = %.4f*e^(%.4f*x) và r2 = %f", a, b, r2);

            end
          
            % IN KẾT QUẢ DỰ ĐOÁN 
        
            app.KtqudonEditField.Value = sprintf("%.6f", y_pred);
  
        end

        % Value changed function: ChnphngphphiquyDropDown
        function ChnphngphphiquyDropDownValueChanged(app, event)

            
        end

        % Value changed function: NhpgitrcndonEditField
        function NhpgitrcndonEditFieldValueChanged(app, event)
          
            
        end

        % Value changed function: NhpgitrxEditField
        function NhpgitrxEditFieldValueChanged(app, event)
  
            
        end

        % Value changed function: NhpgitryEditField
        function NhpgitryEditFieldValueChanged(app, event)
        
            
        end

        % Value changed function: KtquphngtrnhhiquyEditField
        function KtquphngtrnhhiquyEditFieldValueChanged(app, event)
      
        end

        % Value changed function: KtqudonEditField
        function KtqudonEditFieldValueChanged(app, event)

            
        end

        % Value changed function: ChndnguvoDropDown
        function ChndnguvoDropDownValueChanged(app, event)
            val = app.ChndnguvoDropDown.Value;
            if strcmp(val, 'Dữ liệu x,y')
                % Bật ô nhập x,y
                app.xField.Enable = 'on';
                app.yField.Enable = 'on';
        
                % Tắt ô nhập hàm số, bước h
                app.hamsoField.Enable = 'off';
                app.hField.Enable = 'off';
        
            elseif strcmp(val, 'Hàm số')
                % Tắt ô nhập x,y
                app.xField.Enable = 'off';
                app.yField.Enable = 'off';
        
                % Bật ô nhập hàm số, bước h
                app.hamsoField.Enable = 'on';
                app.hField.Enable = 'on';
            end
            
        end

        % Button pushed function: GiiButton_2
        function GiiButton_2Pushed(app, event)
            loaiInput = app.ChndnguvoDropDown.Value;  
            pp = app.PPDropDown.Value;       
            saiso = app.SaisoDropDown.Value;           
            x0 = str2double(app.x0Field.Value);
        
            if isnan(x0)
                app.KtquEditField.Value = "Lỗi: x0 không hợp lệ!";
                return;
            end
        
          
            %  TRƯỜNG HỢP 1: Nhập x,y
          
            if strcmp(loaiInput,"Dữ liệu x,y")
        
                x = str2num(app.xField.Value);
                y = str2num(app.yField.Value);
        
                if isempty(x) || isempty(y)
                    app.KtquEditField.Value = "Lỗi: dữ liệu x,y không hợp lệ!";
                    return;
                end
        
                if length(x) ~= length(y)
                    app.KtquEditField.Value = "Lỗi: x và y phải cùng chiều dài!";
                    return;
                end
        
                % Tìm vị trí gần nhất
                [~, idx] = min(abs(x - x0));
        
                % Kiểm tra biên
                if strcmp(pp,"Xấp xỉ tiến") && idx == length(x)
                    app.KtquEditField.Value = "Lỗi: Không thể dùng tiến tại điểm cuối!";
                    return;
                end
                if strcmp(pp,"Lùi") && idx == 1
                    app.KtquEditField.Value = "Lỗi: Không thể dùng lùi tại điểm đầu!";
                    return;
                end
                if strcmp(pp,"Trung tâm") && (idx == 1 || idx == length(x))
                    app.KtquEditField.Value = "Lỗi: Trung tâm cần điểm trước và sau!";
                    return;
                end
        
                % Tính đạo hàm theo phương pháp
                if strcmp(pp,"Xấp xỉ tiến")
                    dy = (y(idx+1) - y(idx)) / (x(idx+1) - x(idx));
        
                elseif strcmp(pp,"Lùi")
                    dy = (y(idx) - y(idx-1)) / (x(idx) - x(idx-1));
        
                else % Trung tâm
                    dy = (y(idx+1) - y(idx-1)) / (x(idx+1) - x(idx-1));
                end


            %  TRƯỜNG HỢP 2: Nhập hàm f(x)
           
            else
                h = str2double(app.hField.Value);
        
                if isnan(h) || h <= 0
                    app.KtquEditField.Value = "Lỗi: bước h không hợp lệ!";
                    return;
                end
        
                try
                    f = str2func(['@(x)' app.hamsoField.Value]);
                    f(x0); % Kiểm tra hàm có chạy không
                catch
                    app.KtquEditField.Value = "Lỗi: Hàm số không hợp lệ!";
                    return;
                end
        
                % PHƯƠNG PHÁP TIẾN
                if strcmp(pp,"Xấp xỉ tiến")
                    if strcmp(saiso,"O(h)")
                        dy = (f(x0 + h) - f(x0)) / h;
                    else
                        dy = (-f(x0+2*h) + 4*f(x0+h) - 3*f(x0)) / (2*h);
                    end
        
                % PHƯƠNG PHÁP LÙI
                elseif strcmp(pp,"Lùi")
                    if strcmp(saiso,"O(h)")
                        dy = (f(x0) - f(x0-h)) / h;
                    else
                        dy = (3*f(x0) - 4*f(x0-h) + f(x0-2*h)) / (2*h);
                    end
        
                % TRUNG TÂM
                else
                    if strcmp(saiso,"O(h)")
                        dy = (f(x0+h) - f(x0-h)) / (2*h);
                    else
                        dy = (-f(x0+2*h) + 8*f(x0+h) - 8*f(x0-h) + f(x0-2*h)) / (12*h);
                    end
                end
            end
        
            % Xuất kết quả
            app.KtquEditField.Value = sprintf("%.6f", dy);
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)

        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            funcStr = app.FuncEditField.Value;
            a       = app.aEditField.Value;
            b       = app.bEditField.Value;
            es      = app.epsEditField.Value;
            method  = app.MethodDropDown.Value;
        
            % Kiểm tra biểu thức nhập vào
            try
                f = str2func(['@(x)' funcStr]);
                f(a); % kiểm tra thử
            catch
                uialert(app.UIFigure,'Hàm nhập không hợp lệ!','Lỗi');
                return;
            end
        
            % Chọn phương pháp tìm nghiệm
            try
                switch method
                    case 'Chia đôi'
                        if f(a)*f(b) > 0
                            uialert(app.UIFigure,'f(a)*f(b) phải < 0 đối với phương pháp chia đôi','Lỗi khoảng phân ly');
                            return;
                        end
                        [root,iter] = app.bisection_local(f,a,b,es);
        
                    case 'Lặp'
                        x0 = (a+b)/2;
                        [root,iter] = app.fixed_local(f,x0,es);
        
                    case 'Newton (tiếp tuyến)'
                        x0 = (a+b)/2;
                        df = @(x) (f(x+1e-5)-f(x-1e-5))/(2e-5);
                        [root,iter] = app.newton_local(f,df,x0,es);
                end
        
                % Vẽ đồ thị f(x) và nghiệm
                xx = linspace(a,b,200);
                yy = arrayfun(f,xx);
                plot(app.UIAxes_3, xx, yy, 'LineWidth', 1.5);
                hold(app.UIAxes_3,'on');
                yline(app.UIAxes_3, 0, 'k'); % trục hoành
                plot(app.UIAxes_3, root, f(root), 'ro', 'MarkerFaceColor','r');
                hold(app.UIAxes_3,'off');
                grid(app.UIAxes_3,'on');
        
                % Cập nhật kết quả
                app.RootLabel.Text = sprintf('%.6f', root);
                app.IterLabel.Text = sprintf('%d', iter);
        
            catch ME
                uialert(app.UIFigure, ME.message,'Lỗi');
            end
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes_3 = uiaxes(app.NghimTab);
            title(app.UIAxes_3, 'Đồ thị hàm số')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.khoangphanlyLabel = uilabel(app.NghimTab);
            app.khoangphanlyLabel.Text = 'Nhập khoảng phân ly nghiệm [a, b]: ';
            app.CalcButton = uibutton(app.NghimTab, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Tính nghiệm và vẽ đồ thiị';
            app.NhpphngtrnhfxEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhfxEditFieldLabel.Text = 'Nhập phương trình f(x):';
            app.FuncEditField = uieditfield(app.NghimTab, 'text');
            app.aEditFieldLabel = uilabel(app.NghimTab);
            app.aEditFieldLabel.Text = 'a = ';
            app.aEditField = uieditfield(app.NghimTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.NghimTab);
            app.bEditFieldLabel.Text = 'b = ';
            app.bEditField = uieditfield(app.NghimTab, 'numeric');
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép :';
            app.epsEditField = uieditfield(app.NghimTab, 'numeric');
            app.MethodDropDown = uidropdown(app.NghimTab);
            app.MethodDropDown.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)'};
            app.MethodDropDown.Value = 'Chia đôi';
            app.ChnphngphpDropDownLabel_2 = uilabel(app.NghimTab);
            app.ChnphngphpDropDownLabel_2.Text = 'Chọn phương pháp: ';
            app.ketquanghiemLabel = uilabel(app.NghimTab);
            app.ketquanghiemLabel.Text = 'Kết quả nghiệm:';
            app.RootLabel = uilabel(app.NghimTab);
            app.RootLabel.Text = '';
            app.solanlapLabel = uilabel(app.NghimTab);
            app.solanlapLabel.Text = 'Số lần lặp:';
            app.IterLabel = uilabel(app.NghimTab);
            app.IterLabel.Text = '';
            app.noisuy = uitab(app.TabGroup);
            app.noisuy.Title = 'Nội suy';
            app.UIAxes = uiaxes(app.noisuy);
            title(app.UIAxes, 'Đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChnPhngphpDropDownLabel = uilabel(app.noisuy);
            app.ChnPhngphpDropDownLabel.Text = 'Chọn Phương pháp';
            app.phuongphap = uidropdown(app.noisuy);
            app.phuongphap.Items = {' ', 'Larange', 'Newton'};
            app.phuongphap.ValueChangedFcn = createCallbackFcn(app, @phuongphapValueChanged, true);
            app.phuongphap.Value = ' ';
            app.GitrcnnisuyEditFieldLabel = uilabel(app.noisuy);
            app.GitrcnnisuyEditFieldLabel.Text = 'Giá trị cần nội suy';
            app.giatri_x0 = uieditfield(app.noisuy, 'numeric');
            app.giatri_x0.ValueChangedFcn = createCallbackFcn(app, @giatri_x0ValueChanged, true);
            app.KtquathcnisuyTextAreaLabel = uilabel(app.noisuy);
            app.KtquathcnisuyTextAreaLabel.Text = 'Kết quả đa thức nội suy';
            app.ketqua_dathuc = uitextarea(app.noisuy);
            app.ketqua_dathuc.ValueChangingFcn = createCallbackFcn(app, @ketqua_dathucValueChanging, true);
            app.KtqunisuyTextAreaLabel = uilabel(app.noisuy);
            app.KtqunisuyTextAreaLabel.Text = 'Kết quả nội suy';
            app.ketqua_noisuy = uitextarea(app.noisuy);
            app.ketqua_noisuy.ValueChangingFcn = createCallbackFcn(app, @ketqua_noisuyValueChanging, true);
            app.xEditFieldLabel = uilabel(app.noisuy);
            app.xEditFieldLabel.Text = 'x';
            app.giatri_x = uieditfield(app.noisuy, 'text');
            app.giatri_x.ValueChangingFcn = createCallbackFcn(app, @giatri_xValueChanging, true);
            app.yEditFieldLabel = uilabel(app.noisuy);
            app.yEditFieldLabel.Text = 'y';
            app.giatri_y = uieditfield(app.noisuy, 'text');
            app.giatri_y.ValueChangingFcn = createCallbackFcn(app, @giatri_yValueChanging, true);
            app.tim = uibutton(app.noisuy, 'push');
            app.tim.ButtonPushedFcn = createCallbackFcn(app, @timButtonPushed, true);
            app.tim.Text = 'Tìm';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_2 = uiaxes(app.HiquyTab);
            title(app.UIAxes_2, ' Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcndonEditField.ValueChangedFcn = createCallbackFcn(app, @NhpgitrcndonEditFieldValueChanged, true);
            app.xEditFieldLabel_3 = uilabel(app.HiquyTab);
            app.xEditFieldLabel_3.Text = 'x';
            app.NhpgitrxEditField = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrxEditField.ValueChangedFcn = createCallbackFcn(app, @NhpgitrxEditFieldValueChanged, true);
            app.yEditFieldLabel_3 = uilabel(app.HiquyTab);
            app.yEditFieldLabel_3.Text = 'y';
            app.NhpgitryEditField = uieditfield(app.HiquyTab, 'text');
            app.NhpgitryEditField.ValueChangedFcn = createCallbackFcn(app, @NhpgitryEditFieldValueChanged, true);
            app.ChnphngphphiquyDropDownLabel = uilabel(app.HiquyTab);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.ChnphngphphiquyDropDown = uidropdown(app.HiquyTab);
            app.ChnphngphphiquyDropDown.Items = {'Tuyến tính', 'ax^b', 'ae^bx'};
            app.ChnphngphphiquyDropDown.ValueChangedFcn = createCallbackFcn(app, @ChnphngphphiquyDropDownValueChanged, true);
            app.ChnphngphphiquyDropDown.Value = 'Tuyến tính';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.GiiButton = uibutton(app.HiquyTab, 'push');
            app.GiiButton.ButtonPushedFcn = createCallbackFcn(app, @GiiButtonPushed, true);
            app.GiiButton.Text = 'Giải';
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy ';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.KtquphngtrnhhiquyEditField.ValueChangedFcn = createCallbackFcn(app, @KtquphngtrnhhiquyEditFieldValueChanged, true);
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'text');
            app.KtqudonEditField.ValueChangedFcn = createCallbackFcn(app, @KtqudonEditFieldValueChanged, true);
            app.tichphan = uitab(app.TabGroup);
            app.tichphan.Title = 'Tích Phân';
            app.chon_kieu_tinh = uiswitch(app.tichphan, 'slider');
            app.chon_kieu_tinh.Items = {'Nhập giá trị', 'Nhập hàm'};
            app.chon_kieu_tinh.ValueChangedFcn = createCallbackFcn(app, @chon_kieu_tinhValueChanged, true);
            app.chon_kieu_tinh.Value = 'Nhập giá trị';
            app.xEditFieldLabel_2 = uilabel(app.tichphan);
            app.xEditFieldLabel_2.Text = 'x';
            app.giatri_x_2 = uieditfield(app.tichphan, 'text');
            app.giatri_x_2.ValueChangedFcn = createCallbackFcn(app, @giatri_x_2ValueChanged, true);
            app.yEditFieldLabel_2 = uilabel(app.tichphan);
            app.yEditFieldLabel_2.Text = 'y';
            app.giatri_y_2 = uieditfield(app.tichphan, 'text');
            app.giatri_y_2.ValueChangedFcn = createCallbackFcn(app, @giatri_y_2ValueChanged, true);
            app.ChnphngphpDropDownLabel = uilabel(app.tichphan);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.chon_pp = uidropdown(app.tichphan);
            app.chon_pp.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.chon_pp.ValueChangedFcn = createCallbackFcn(app, @chon_ppValueChanged, true);
            app.chon_pp.Value = 'Hình thang';
            app.CnaEditFieldLabel = uilabel(app.tichphan);
            app.CnaEditFieldLabel.Text = 'Cận a';
            app.can_a = uieditfield(app.tichphan, 'text');
            app.can_a.ValueChangedFcn = createCallbackFcn(app, @can_aValueChanged, true);
            app.CnbEditFieldLabel = uilabel(app.tichphan);
            app.CnbEditFieldLabel.Text = 'Cận b';
            app.can_b = uieditfield(app.tichphan, 'text');
            app.can_b.ValueChangedFcn = createCallbackFcn(app, @can_bValueChanged, true);
            app.NEditFieldLabel = uilabel(app.tichphan);
            app.NEditFieldLabel.Text = 'N';
            app.n = uieditfield(app.tichphan, 'numeric');
            app.n.ValueChangedFcn = createCallbackFcn(app, @nValueChanged, true);
            app.tim_tp = uibutton(app.tichphan, 'push');
            app.tim_tp.ButtonPushedFcn = createCallbackFcn(app, @tim_tpPushed, true);
            app.tim_tp.Text = 'Tìm';
            app.KtquTextAreaLabel = uilabel(app.tichphan);
            app.KtquTextAreaLabel.Text = 'Kết quả';
            app.kq = uitextarea(app.tichphan);
            app.kq.ValueChangedFcn = createCallbackFcn(app, @kqValueChanged, true);
            app.fLabel = uilabel(app.tichphan);
            app.fLabel.Text = 'f';
            app.nhapham = uieditfield(app.tichphan, 'text');
            app.nhapham.ValueChangedFcn = createCallbackFcn(app, @nhaphamValueChanged, true);
            app.note = uilabel(app.tichphan);
            app.note.Text = 'Note: nhập theo dạng:  .*   ./   .^';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.GiiButton_2 = uibutton(app.ohmTab, 'push');
            app.GiiButton_2.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_2Pushed, true);
            app.GiiButton_2.Text = 'Giải';
            app.ChndnguvoDropDownLabel = uilabel(app.ohmTab);
            app.ChndnguvoDropDownLabel.Text = 'Chọn dạng đầu vào';
            app.ChndnguvoDropDown = uidropdown(app.ohmTab);
            app.ChndnguvoDropDown.Items = {'Dữ liệu x,y', 'Hàm số'};
            app.ChndnguvoDropDown.ValueChangedFcn = createCallbackFcn(app, @ChndnguvoDropDownValueChanged, true);
            app.ChndnguvoDropDown.Value = 'Dữ liệu x,y';
            app.NhpgitrxEditField_2Label = uilabel(app.ohmTab);
            app.NhpgitrxEditField_2Label.Text = 'Nhập giá trị x';
            app.xField = uieditfield(app.ohmTab, 'text');
            app.NhpgitryEditField_2Label = uilabel(app.ohmTab);
            app.NhpgitryEditField_2Label.Text = 'Nhập giá trị y';
            app.yField = uieditfield(app.ohmTab, 'text');
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.hamsoField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.hField = uieditfield(app.ohmTab, 'text');
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KtquEditField = uieditfield(app.ohmTab, 'text');
            app.ChnsaisDropDownLabel = uilabel(app.ohmTab);
            app.ChnsaisDropDownLabel.Text = 'Chọn sai số';
            app.SaisoDropDown = uidropdown(app.ohmTab);
            app.SaisoDropDown.Items = {'O(h)', 'O(h^2)'};
            app.SaisoDropDown.Value = 'O(h)';
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.x0Field = uieditfield(app.ohmTab, 'text');
            app.ChnphngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.PPDropDown = uidropdown(app.ohmTab);
            app.PPDropDown.Items = {'Xấp xỉ tiến', 'Lùi', 'Trung tâm'};
            app.PPDropDown.Value = 'Xấp xỉ tiến';
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.HTMLGioiThieu = uihtml(app.GiithiunhmTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DO_AN_PPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end